classdef ClimarveFullApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigura                       matlab.ui.Figure
        GrupoAbas                      matlab.ui.container.TabGroup
        AbaInicio                      matlab.ui.container.Tab
        AnexosButton                   matlab.ui.control.Button
        TabelaDasUnidadesDAIKINButton  matlab.ui.control.Button
        TabeladeIsolamentosButton      matlab.ui.control.Button
        TabeladasRegiesButton          matlab.ui.control.Button
        SairButton                     matlab.ui.control.Button
        SobreogrupoButton              matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        ClimarveDimensionamentodearcondicionadoLabel  matlab.ui.control.Label
        BotaoIniciar                   matlab.ui.control.Button
        ImagemLogo                     matlab.ui.control.Image
        AbaRegiao                      matlab.ui.container.Tab
        Image4                         matlab.ui.control.Image
        RegiodahabitaoLabel            matlab.ui.control.Label
        AnteriorButton                 matlab.ui.control.Button
        SairButton_2                   matlab.ui.control.Button
        RecomecarButton                matlab.ui.control.Button
        LimparButton                   matlab.ui.control.Button
        BotaoIrTipologiaEnvolvente     matlab.ui.control.Button
        mensagemP                      matlab.ui.control.Label
        ConcelhoDropDown               matlab.ui.control.DropDown
        ConcelhoLabel                  matlab.ui.control.Label
        DistritoDropdown               matlab.ui.control.DropDown
        DistritoLabel                  matlab.ui.control.Label
        AbaEnvolvente                  matlab.ui.container.Tab
        DivisoesConteiner              matlab.ui.container.Panel
        RecomecarButton_2              matlab.ui.control.Button
        LimparButton_2                 matlab.ui.control.Button
        SairButton_3                   matlab.ui.control.Button
        AnteriorButton_2               matlab.ui.control.Button
        BotaoAplicarEnvolvente         matlab.ui.control.Button
        IsolamentoJanelasPanel         matlab.ui.container.Panel
        IsolamentoParedePanel          matlab.ui.container.Panel
        AdicionarDivisoCheckBox        matlab.ui.control.CheckBox
        Panel                          matlab.ui.container.Panel
        NovaDivisoEditField            matlab.ui.control.EditField
        NovaDivisoEditFieldLabel       matlab.ui.control.Label
        GerarNovaDivisoButton          matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        GerarDivisoesButton            matlab.ui.control.Button
        TipologiaDropDown              matlab.ui.control.DropDown
        TipologiaDropDownLabel_2       matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        AbaDivisoes                    matlab.ui.container.Tab
        SairButton_4                   matlab.ui.control.Button
        RecomecarButton_3              matlab.ui.control.Button
        AnteriorButton_3               matlab.ui.control.Button
        LimparButton_3                 matlab.ui.control.Button
        BotaoCalcular                  matlab.ui.control.Button
        TabelaDivisoes                 matlab.ui.control.Table
        Image8                         matlab.ui.control.Image
        AbaResultados                  matlab.ui.container.Tab
        SairButton_5                   matlab.ui.control.Button
        RecomecarButton_4              matlab.ui.control.Button
        AnteriorButton_4               matlab.ui.control.Button
        BotaoExportar                  matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        Resumo                         matlab.ui.control.Label
        TabelaResultados               matlab.ui.control.Table
        Image9                         matlab.ui.control.Image
        Abafim                         matlab.ui.container.Tab
        RecomearButton                 matlab.ui.control.Button
        SairButton_6                   matlab.ui.control.Button
        ClculoPrecisoConfortoGarantidoLabel  matlab.ui.control.Label
        ObrigadoporescolherCLIMARVELabel  matlab.ui.control.Label
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
    end


    properties (Access = private)
        catalogoInt             % unidades interiores Daikin
        catalogoExt             % unidades exteriores Daikin
        tabelaIsolamentos       % tabela com tipos de paredes / U-values
        tabelaJanelas           % tabela com tipos de janelas / U-values e SHGC
        resultados              % tabela com resultados calculado
        tabelaZonas             % tabela Excel com distritos/concelhos e parametros
        concelhoSelecionado     % nome do concelho escolhido
        GD_REF                  % Graus-dia
        theta_inv_REF           % Temp. projeto inverno
        theta_ver_REF           % Temp. projeto verao
        G_inv_sul               % Radiacao inverno (Sul)
        G_verao_N               % Radiacao verão (Norte)
        G_verao_E               % Radiacao verão (Este)
        G_verao_S               % Radiacao verão (Sul)
        G_verao_O               % Radiacao verão (Oeste)
        z_REF_m                 % Altitude
        Mref                    %Meses de aquecimento   
        tabelaEditada = false;  % indica se o utilizador alterou a tabela
        gerouTipologia = false;   % true só depois de clicar em "Gerar Divisões"
        envolventeEditada = false;   % indica se o utilizador alterou paredes/janelas
        erroTabela = false;
    end

    methods (Access = private)
        % =========================================================
        %  FUNÇÃO DE INICIALIZAÇÃO DA APLICAÇÃO (Startup)
        % - Executa automaticamente no arranque da app
        % - Carrega ficheiros Excel (zonas, isolamentos)
        % - Preenche dropdowns iniciais
        % - Configura tabela de divisões
        % - Esconde painel de nova divisão
        % =========================================================
        function funcaoInicializacao(app)                
            % Carregar tabela de Zonas Climáticas (Excel)           
            try
                app.tabelaZonas = readtable('SCE_Zonas_por_Distrito_COMPLETO.xlsx');
            catch
                uialert(app.UIFigura,'Erro ao carregar ficheiro das zonas climáticas.','Erro');
                app.tabelaZonas = table();   % Evita que a app crash
            end
      
            % Carregar tabelas de isolamentos (Parede e Janela)

            % --- Tabela de Paredes ---
            try
                app.tabelaIsolamentos = readtable('isolamentos.xlsx', ...
                    'Sheet', 'Parede', 'TextType', 'string');
                app.tabelaIsolamentos.Properties.VariableNames = ...
                    {'Tipo_Parede','Descricao','U_W_m2K'};
            catch
                uialert(app.UIFigura,'Erro ao carregar tabela de paredes.','Erro');
                app.tabelaIsolamentos = table();
            end

            % --- Tabela de Janelas ---
            try
                app.tabelaJanelas = readtable('isolamentos.xlsx', ...
                    'Sheet', 'Janela', 'TextType', 'string');
                app.tabelaJanelas.Properties.VariableNames = ...
                    {'Tipo_Janela','Descricao','U_W_m2K','SHGC'};
            catch
                uialert(app.UIFigura,'Erro ao carregar tabela de janelas.','Erro');
                app.tabelaJanelas = table();
            end
     
            % Inicializar Dropdown de Distritos e Concelhos

            % Concelhos começam vazios e desativados (só após escolher distrito)
            app.ConcelhoDropDown.Items = {};
 
            % Configurar Tabela de Divisões (vazia no início)          

            app.TabelaDivisoes.Data = {};             % Sem dados iniciais
            app.TabelaDivisoes.RowName = {};          % Retira nomes de linhas
            % Permite editar apenas de colunas 2 a 6
            app.TabelaDivisoes.ColumnEditable = ...
                [false true true true true true false false];            
        end

        % HELPERS  
        %% =========================================================
        % Retorna lista de divisões padrão conforme a tipologia T0..T5
        
        function divisoes = obterDivisoesPadraoPorTipologia(~, tip)

            switch tip
                case "T0", divisoes = {'Divisão Única'};
                case "T1", divisoes = {'Sala','Cozinha','Quarto 1'};
                case "T2", divisoes = {'Sala','Cozinha','Quarto 1','Quarto 2'};
                case "T3", divisoes = {'Sala','Cozinha','Quarto 1','Quarto 2','Quarto 3'};
                case "T4", divisoes = {'Sala','Cozinha','Quarto 1','Quarto 2','Quarto 3','Quarto 4'};
                case "T5", divisoes = {'Sala','Cozinha','Quarto 1','Quarto 2','Quarto 3','Quarto 4','Quarto 5'};
                otherwise 
                     divisoes = {};   
            end
        end

        % Cria tabela padrão com as divisões da tipologia
        % (preenche com valores iniciais recomendados e editáveis)
       
        function novaTabela = criarTabelaPadrao(~, divisoesBase)

            n = numel(divisoesBase);      % Número de divisões da tipologia selecionada
            novaTabela = cell(n,8);       % Cria tabela vazia com 8 colunas

            for i = 1:n
                novaTabela{i,1} = divisoesBase{i};             % Nome da divisão
                novaTabela{i,2} = 20;                          % Área (m²) inicial recomendada
                novaTabela{i,3} = 2.7;                         % Altura (m)
                novaTabela{i,4} = 1;                           % Nº de pessoas
                novaTabela{i,5} = 6;                           % Área janelas (m²)
                novaTabela{i,6} = 'S';                         % Orientação inicial (Sul)
                novaTabela{i,7} = 'Capoto EPS 6 cm + Tijolo Furado + Reboco';   % Parede padrão
                novaTabela{i,8} = 'PVC com vidro duplo 4/16/4 baixo emissivo';  % Janela padrão
            end
        end
        %% =========================================================
        % Atualiza somente os nomes das divisões, mantendo os dados já preenchidos
          
        function ativarOuRemoverDivisao(app, checkbox, nomeDivisao)
            % Função chamada quando clica no checkbox "Excluir".
            % Se o checkbox ficar marcado (1), a divisão é removida da tabela e dos paineis.
            % Depois atualizamos os painéis visualmente.

            dados = app.TabelaDivisoes.Data;
            % Guarda os dados atuais da tabela das divisões (cell array).
           
            if checkbox.Value == 1

                % Encontrar a linha da divisão pelo nome
                linha = find(strcmp(dados(:,1), nomeDivisao));
               
                if ~isempty(linha) % Se encontrou a divisão
                    dados(linha,:) = []; % Remove essa linha completamente
                    app.TabelaDivisoes.Data = dados; % Atualiza a tabela
                end

                % Apaga elementos dessa divisão nos painéis de isolamento (Parede e Janela)
                delete(findall(app.IsolamentoParedePanel, 'Tag', nomeDivisao));
                delete(findall(app.IsolamentoJanelasPanel,'Tag', nomeDivisao));
                % Usamos o Tag com o nome da divisão para apagar todos os elementos associados
            end
            
            % ATUALIZAR OS PAINÉIS VISUAIS (Parede e Janelas)
          
            divisoesAtuais = app.TabelaDivisoes.Data(:,1);
            % Obtemos os nomes das divisões ativas (sem valores de área, altura,...)

            atualizarPainelIsolamento(app, divisoesAtuais);
            % Redesenha os dropdowns e checkboxes,só com as divisões ativas

        end


        function atualizarPainelIsolamento(app, divisoesBase)

    % ===== GARANTIR TABELAS DE ISOLAMENTO =====
    if isempty(app.tabelaIsolamentos) || ~istable(app.tabelaIsolamentos)
        try
            app.tabelaIsolamentos = readtable('isolamentos.xlsx', ...
                'Sheet','Parede','TextType','string');
            app.tabelaIsolamentos.Properties.VariableNames = ...
                {'Tipo_Parede','Descricao','U_W_m2K'};
        catch
            app.tabelaIsolamentos = table();
        end
    end

    if isempty(app.tabelaJanelas) || ~istable(app.tabelaJanelas)
        try
            app.tabelaJanelas = readtable('isolamentos.xlsx', ...
                'Sheet','Janela','TextType','string');
            app.tabelaJanelas.Properties.VariableNames = ...
                {'Tipo_Janela','Descricao','U_W_m2K','SHGC'};
        catch
            app.tabelaJanelas = table();
        end
    end

            % APAGAR TODOS OS COMPONENTES ANTIGOS(Ñ duplicar)
            delete(findall(app.IsolamentoParedePanel, 'Tag', 'DivisaoItem'));
            delete(findall(app.IsolamentoJanelasPanel, 'Tag', 'DivisaoItem'));
            % Se já houver tabela de divisões, usamos os nomes dela
            if ~isempty(app.TabelaDivisoes.Data)
                divisoesBase = app.TabelaDivisoes.Data(:,1);
            end

            totalDivs = numel(divisoesBase);
            if totalDivs == 0
                return;
            end

            % Altura disponível dentro do painel
            alturaPainel = app.IsolamentoParedePanel.Position(4);

            margemTopo  = 80;   % afastamento do topo
            margemBaixo = 20;   % margem inferior

            if totalDivs == 1
                % Coloca-o no topo (ou centro)
                nomesPos = alturaPainel - margemTopo;
            else
                espaco = (alturaPainel - margemTopo - margemBaixo) / (totalDivs - 1);
                nomesPos = alturaPainel - margemTopo : -espaco : margemBaixo;
            end
            % Coordenadas horizontais
            xLabelParede   = 15;  xDropParede   = 160;  xCheck = 330;
            xLabelJanela   = 15;  xDropJanela   = 160;
            
            % ===== ITENS SEGUROS PARA DROPDOWNS =====
            itemsParede = {};
            itemsJanela = {};

            if istable(app.tabelaIsolamentos) && ...
                    ~isempty(app.tabelaIsolamentos) && ...
                    ismember('Tipo_Parede', app.tabelaIsolamentos.Properties.VariableNames)
                itemsParede = app.tabelaIsolamentos.Tipo_Parede;
            end

            if istable(app.tabelaJanelas) && ...
                    ~isempty(app.tabelaJanelas) && ...
                    ismember('Tipo_Janela', app.tabelaJanelas.Properties.VariableNames)
                itemsJanela = app.tabelaJanelas.Tipo_Janela;
            end

            for i = 1:totalDivs
                yPos = nomesPos(i);

                nomeDiv = divisoesBase{i};

                % ===== LABEL PAREDE =====
                uilabel(app.IsolamentoParedePanel,...
                    'Text', nomeDiv, ...
                    'Tag','DivisaoItem', ...
                    'Position',[xLabelParede yPos 120 22]);

                % ===== DROPDOWN PAREDE =====
                uidropdown(app.IsolamentoParedePanel, ...
                    'Items', itemsParede, ...
                    'Tag','DivisaoItem', ...
                    'Position',[xDropParede yPos 160 22], ...
                    'ValueChangedFcn', @(src,evt) marcarEnvolventeEditada(app));

                % ===== LABEL JANELA =====
                uilabel(app.IsolamentoJanelasPanel, ...
                    'Text', nomeDiv, ...
                    'Tag','DivisaoItem', ...
                    'Position',[xLabelJanela yPos 120 22]);

                % ===== DROPDOWN JANELA =====
                uidropdown(app.IsolamentoJanelasPanel, ...
                    'Items', itemsJanela, ...
                    'Tag','DivisaoItem', ...
                    'Position',[xDropJanela yPos 160 22], ...
                    'ValueChangedFcn', @(src,evt) marcarEnvolventeEditada(app));

                % ===== CHECKBOX EXCLUIR =====
                uicheckbox(app.IsolamentoParedePanel, ...
                    'Text','Excluir', ...
                    'Tag','DivisaoItem', ...
                    'Position',[xCheck yPos 70 22], ...
                    'ValueChangedFcn', @(src,evt) ativarOuRemoverDivisao(app, src, nomeDiv));
            end
        end

        function marcarEnvolventeEditada(app)
            app.envolventeEditada = true;
        end

       
        % CALLBACK DE VALIDAÇÃO DE VALORES NA TABELA
        
        function TabelaDivisoesCellEdit(app, event)

            % Marca que o utilizador alterou a tabela
            app.tabelaEditada = true;

            linha = event.Indices(1);
            coluna = event.Indices(2);
            novoValor = event.NewData;

    % (resto do teu código de validação continua igual)

            % =========================================
            % BLOQUEAR VALORES NEGATIVOS ou NÃO NUMÉRICOS
            % mas APENAS para colunas numéricas (2,3,4,5)
           
            if ismember(coluna, [2,3,4,5])
                if isnan(novoValor) || ~isnumeric(novoValor) || novoValor < 0
                    uialert(app.UIFigura, ...
                        'Valor inválido! Prima ENTER para corrigir antes de continuar.', ...
                        'Erro');

                    app.erroTabela = true;  
                    event.Source.Data{linha,coluna} = event.PreviousData;
                    return;
                end
            end
            app.erroTabela = false;
       
            % VALIDAÇÕES POR COLUNA NA TABELA DAS DIVISÕES
            
            switch coluna

                % === Área (m²) ===
                case 2
                    if novoValor < 8 || novoValor > 60
                        uialert(app.UIFigura,'Área deve estar entre 8 e 60 m².','Erro');
                        event.Source.Data{linha,coluna} = event.PreviousData;
                        return;
                    end

                    % === Altura (m) ===
                case 3
                    if novoValor < 2.2 || novoValor > 3.5
                        uialert(app.UIFigura,'Altura deve estar entre 2.2 e 3.5 m.','Erro');
                        event.Source.Data{linha,coluna} = event.PreviousData;
                        return;
                    end
                    % === Área de Janelas (m²) ===
                case 5
                    % Verificar se valor de janela é absurdo
                    areaDivisao = event.Source.Data{linha,2}; % área da divisão
                    if novoValor > areaDivisao * 0.4   % regra: janelas ≤ 40% da área
                        uialert(app.UIFigura,"Área das janelas não pode exceder 40% da área do compartimento.","Erro");
                        event.Source.Data{linha,coluna} = event.PreviousData;
                        return;
                    end
            end
        end
        function inicializarTabelaVazia(app)
            % Garante que a tabela existe antes de adicionar divisões manuais

            if isempty(app.TabelaDivisoes.Data)

               app.TabelaDivisoes.Data = cell(0,8); 

                % Permitir edição só nas colunas certas
                app.TabelaDivisoes.ColumnEditable = ...
                    [false true true true true true false false];

                % Formatos (orientação em dropdown, etc.)
                app.TabelaDivisoes.ColumnFormat = {[],[],[],...
                    {'1','2','3','4','5','6'},[],{'N','S','E','O'},[],[]};

                % Callback de validação
                app.TabelaDivisoes.CellEditCallback = ...
                    createCallbackFcn(app, @TabelaDivisoesCellEdit, true);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BotaoIniciar
        function BotaoIniciarButtonPushed(app, event)
                  app.GrupoAbas.SelectedTab = app.AbaRegiao;
        end

        % Button pushed function: SobreogrupoButton
        function SobreogrupoButtonPushed(app, event)
            Sobreogrupo;
        end

        % Value changed function: DistritoDropdown
        function DistritoDropdownValueChanged(app, event)

            distritoSel = string(app.DistritoDropdown.Value);

            if distritoSel == "" || distritoSel == "Selecione um distrito"

                % Bloquear e limpar concelho
                app.ConcelhoDropDown.Items = {};
                app.ConcelhoDropDown.Value = {};
                app.ConcelhoDropDown.Enable = 'off';
                app.ConcelhoLabel.Enable = 'off';

                uialert(app.UIFigura, ...
                    'Distrito inválido. Selecione um distrito válido.', ...
                    'Aviso', 'Icon','warning');
                return;
            end

            ficheiroZonas = 'SCE_Zonas_por_Distrito_COMPLETO.xlsx';

            try
                app.tabelaZonas = readtable(ficheiroZonas, 'Sheet', distritoSel);
            catch
                uialert(app.UIFigura, ['Erro ao ler distrito: ' distritoSel], 'Erro');
                return;
            end

            if ismember('Concelho', app.tabelaZonas.Properties.VariableNames)
                concelhosDistrito = unique(app.tabelaZonas.Concelho);
            else
                uialert(app.UIFigura,'Coluna "Concelho" não encontrada.','Erro');
                return;
            end

            app.ConcelhoDropDown.Items = concelhosDistrito;
            app.ConcelhoDropDown.Value = concelhosDistrito(1);
            app.ConcelhoDropDown.Enable = 'on';
            app.ConcelhoLabel.Enable = 'on';
        end

        % Value changed function: ConcelhoDropDown
        function ConcelhoDropDownValueChanged(app, event)
           if app.ConcelhoDropDown.Enable == "off" || isempty(app.tabelaZonas)
                uialert(app.UIFigura, ...
                    'Selecione primeiro um distrito válido.', ...
                    'Aviso', 'Icon','warning');
                return;
            end

            concelhoSel = string(app.ConcelhoDropDown.Value);

            linha = app.tabelaZonas(strcmp(app.tabelaZonas.Concelho, concelhoSel), :);

            if isempty(linha)
                app.mensagemP.Text = 'Dados não encontrados para este concelho.';
                return;
            end
            concelhoSel = string(app.ConcelhoDropDown.Value);
            % Filtrar linha correspondente ao concelho
            linha = app.tabelaZonas(strcmp(app.tabelaZonas.Concelho, concelhoSel), :);

            if isempty(linha)
                app.mensagemP.Text = 'Dados não encontrados para este concelho.';
                return;
            end
         
            % Conversão segura (suporta texto, número, célula, espaços)
            app.GD_REF        = str2double(string(linha.GD_REF));
            app.theta_inv_REF = str2double(string(linha.theta_inv_REF));
            app.theta_ver_REF = str2double(string(linha.theta_ver_REF));
            app.G_inv_sul     = str2double(string(linha.G_inv_sul_kWhm2mes));
            app.G_verao_N     = str2double(string(linha.G_verao_N_kWhm2mes));
            app.G_verao_E     = str2double(string(linha.G_verao_E_kWhm2mes));
            app.G_verao_S     = str2double(string(linha.G_verao_S_kWhm2mes));
            app.G_verao_O     = str2double(string(linha.G_verao_O_kWhm2mes));
            app.z_REF_m       = str2double(string(linha.z_REF_m));
            app.Mref          = str2double(string(linha.Mref));

            msg = sprintf([ ...
                'Concelho: %s\n' ...
                'GD_REF: %.0f graus-dia\n' ...
                'Temperaturas de projeto:\n' ...
                '   Inverno: %.1f°C   |   Verão: %.1f°C\n' ...
                'Radiação solar:\n' ...
                '   Inverno (sul): %.0f kWh/m².mês\n' ...
                '   Verão (N,E,S,O): %.0f | %.0f | %.0f | %.0f kWh/m².mês\n' ...
                'Altitude: %.0f m'], ...
                concelhoSel, app.GD_REF, app.theta_inv_REF, app.theta_ver_REF, ...
                app.G_inv_sul, app.G_verao_N, app.G_verao_E, app.G_verao_S, app.G_verao_O, ...
                app.z_REF_m);

            app.mensagemP.Text = msg;
        end

        % Button pushed function: BotaoIrTipologiaEnvolvente
        function BotaoIrTipologiaEnvolventePushed(app, event)
           
    % VALIDAÇÃO DO DISTRITO
   
    if isempty(app.DistritoDropdown.Value) || app.DistritoDropdown.Value == ""
        uialert(app.UIFigura, ...
            'Por favor selecione um distrito antes de continuar.', ...
            'Distrito em falta', ...
            'Icon','warning');
        return;
    end
    
    % VALIDAÇÃO DO CONCELHO

    if isempty(app.ConcelhoDropDown.Value) || app.ConcelhoDropDown.Value == ""
        uialert(app.UIFigura, ...
            'Por favor selecione um concelho válido antes de continuar.', ...
            'Concelho em falta', ...
            'Icon','warning');
        return;
    end
  
    app.GrupoAbas.SelectedTab = app.AbaEnvolvente;
        end

        % Button pushed function: GerarDivisoesButton
        function GerarDivisoesButtonPushed(app, event)
            % Verificar se foi escolhida uma tipologia
            if isempty(app.TipologiaDropDown.Value)
                uialert(app.UIFigura, ...
                    'Selecione primeiro uma tipologia antes de gerar divisões.', ...
                    'Tipologia em falta', ...
                    'Icon','warning');
                return;
            end

            app.gerouTipologia = true;

            % Garantir que tabelas estão carregadas como TABLE
            app.tabelaIsolamentos = readtable('isolamentos.xlsx','Sheet','Parede');
            app.tabelaJanelas     = readtable('isolamentos.xlsx','Sheet','Janela');

            % Obter tipologia escolhida
            tip = string(app.TipologiaDropDown.Value);

            % Criar lista de divisões
            divisoesBase = obterDivisoesPadraoPorTipologia(app, tip);

            % Garantir que a tabela existe
            inicializarTabelaVazia(app);


            % Divisões atuais
            dadosAtuais = app.TabelaDivisoes.Data;

            % 1) Manter apenas divisões Manuais
            divisoesManuais = {};
        
            % 2) Criar divisões da nova tipologia
            tip = string(app.TipologiaDropDown.Value);
            divisoesBase = obterDivisoesPadraoPorTipologia(app, tip);

            divisoesTipologia = {};

            for i = 1:numel(divisoesBase)
                divisoesTipologia(end+1,:) = { ...
                    divisoesBase{i}, 20, 2.7, 1, 6, 'S', ...
                    'Capoto EPS 6 cm + Tijolo Furado + Reboco', ...
                    'PVC com vidro duplo 4/16/4 baixo emissivo', ...
                    };
            end

            % 3) Juntar MANUAIS + NOVA TIPOLOGIA
            app.TabelaDivisoes.Data = [divisoesManuais; divisoesTipologia];

            % Atualizar painéis
            atualizarPainelIsolamento(app, app.TabelaDivisoes.Data(:,1));

            % Flags
            app.gerouTipologia = true;
            app.tabelaEditada = false;
            app.envolventeEditada = false;


        end

        % Value changed function: AdicionarDivisoCheckBox
        function AdicionarDivisoCheckBoxValueChanged(app, event)

            if app.AdicionarDivisoCheckBox.Value == 1
                app.Panel.Visible = 'on';
            else
                app.Panel.Visible = 'off';
            end

        end

        % Button pushed function: GerarNovaDivisoButton
        function GerarNovaDivisoButtonPushed(app, event)
         
            if ~app.gerouTipologia
                uialert(app.UIFigura, ...
                    "Para adicionar divisões extra tem que primeiro gerar uma tipologia." + newline + newline + ...
                    "Caso pretenda climatizar apenas as divisões fora da tipologia base, " + ... 
                    "tem primeiro gerar uma tipologia e depois eliminar as divisões não desejadas.", ....", ...
                    "Ação obrigatória", ...
                    "Icon","warning");
                return;
            end
            inicializarTabelaVazia(app); % Garantir que a tabela existe
            dados = app.TabelaDivisoes.Data;
            numAtuais = size(dados,1); % Contar divisões atuais

            % Define limite:Tipologia= originais+2 || Ñ Tipologia= Máx=2
            if app.gerouTipologia
                divisoesOriginais = obterDivisoesPadraoPorTipologia(app, app.TipologiaDropDown.Value);
                maxPermitido = numel(divisoesOriginais) + 2;
            else
                maxPermitido = 2;
            end

            if numAtuais >= maxPermitido
                uialert(app.UIFigura, ...
                    ['Número máximo de divisões atingido.' newline,newline...
                    'Pode no máximo adicionar até duas divisões extra.'], ...
                    'Limite', ...
                    'Icon','warning');
                return;
            end
           
            novaDiv = strtrim(app.NovaDivisoEditField.Value); % Nome da nova divisão
            if isempty(novaDiv)
                uialert(app.UIFigura,'Introduza um nome válido.','Erro');
                return;
            end
          
            if ~isempty(dados) && size(dados,2) >= 1 % Evitar nomes repetidos
                if any(strcmp(dados(:,1), novaDiv))
                    uialert(app.UIFigura,'Já existe uma divisão com esse nome.','Erro');
                    return;
                end
            end
           
            novaLinha = { novaDiv, 20, 2.7, 1, 6, 'S', ...
                'Capoto EPS 6 cm + Tijolo Furado + Reboco', ...
                'PVC com vidro duplo 4/16/4 baixo emissivo', ...
                 };


            app.TabelaDivisoes.Data = [dados; novaLinha];
            app.tabelaEditada = false;        
            app.envolventeEditada = false;    
            app.gerouTipologia = true;
            atualizarPainelIsolamento(app, app.TabelaDivisoes.Data(:,1));
            app.NovaDivisoEditField.Value = '';
        end

        % Button pushed function: BotaoAplicarEnvolvente
        function BotaoAplicarEnvolventeButtonPushed(app, event)
             % 1) Verificar se existem divisões
            if isempty(app.TabelaDivisoes.Data)
                uialert(app.UIFigura,'Gere primeiro as divisões.','Aviso');
                return;
            end

            % 2) Aviso se envolvente não foi alterada
            if ~app.envolventeEditada
                resposta = uiconfirm(app.UIFigura, ...
                    ['Não realizou nenhuma alteração às envolventes de parede e janela.' newline newline ...
                    'Confirma que estes são os envolventes reais da sua habitação?'], ...
                    'Confirmação necessária', ...
                    'Options', {'Confirmar','Cancelar'}, ...
                    'DefaultOption','Cancelar', ...
                    'Icon','warning');

                if strcmp(resposta,'Cancelar')
                    return;
                end
            end    
                % Obter dados atuais da TabelaDivisoes (cell array)
                T = app.TabelaDivisoes.Data;

                % Encontrar dropdowns
                dropsParede = findall(app.IsolamentoParedePanel,'Type','uidropdown','Tag','DivisaoItem');
                dropsJanela = findall(app.IsolamentoJanelasPanel,'Type','uidropdown','Tag','DivisaoItem');

                % Converter para array normal
                dropsParede = dropsParede(:);
                dropsJanela = dropsJanela(:);
    
                % --- ORDENAR PELA POSIÇÃO Y (de cima para baixo) ---
                [~, idxP] = sort(cellfun(@(c) c.Position(2), num2cell(dropsParede)), 'descend');
                [~, idxJ] = sort(cellfun(@(c) c.Position(2), num2cell(dropsJanela)), 'descend');
    
                dropsParede = dropsParede(idxP);
                dropsJanela = dropsJanela(idxJ);
                 
                n = size(T,1); % Número de divisões
    
                % Preencher tabela com valores escolhidos
                for i = 1:n
                    if i <= length(dropsParede)
                       T{i,7} = char(strtrim(string(dropsParede(i).Value)));
                    end
                    if i <= length(dropsJanela)
                        T{i,8} = char(strtrim(string(dropsJanela(i).Value)));
                    end
                end             
                app.TabelaDivisoes.Data = T;              
                app.GrupoAbas.SelectedTab = app.AbaDivisoes; % Atualizar tabela  
        end

        % Button pushed function: BotaoCalcular
        function BotaoCalcularPushed(app, event)
                 
                if ~app.tabelaEditada
                    resposta = uiconfirm(app.UIFigura, ...
                        ['Atenção:' newline ...
                        'Não alterou nenhum dado na tabela das divisões.' newline ...
                        'Tem a certeza que os valores correspondem à sua Habitação?'], ...
                        'Confirmação necessária', ...
                        'Options', {'Continuar','Cancelar'}, ...
                        'DefaultOption','Cancelar', ...
                        'Icon','warning');

                    if strcmp(resposta,'Cancelar')
                        return;
                    end
                end

                if app.erroTabela
                    uialert(app.UIFigura, ...
                        ['Existem valores inválidos na tabela.' newline ...
                        'Corrija a célula e prima ENTER antes de calcular.'], ...
                        'Erro na tabela');
                    return;
                end

                % Calcula cargas térmicas e seleciona automaticamente
                % as UNIDADES INTERIORES e EXTERIORES Daikin              
                %% 1) Carregar catálogos Daikin (interiores e exteriores)
                try
                    app.catalogoInt = readtable("daikin_unidades.xlsx", ...
                        "Sheet","UnidadesInteriores","TextType","string");
                    app.catalogoExt = readtable("daikin_unidades.xlsx", ...
                        "Sheet","UnidadesExteriores","TextType","string");
                catch
                    uialert(app.UIFigura,"Erro ao carregar ficheiro daikin_unidades.xlsx","Erro");
                    return;
                end    
                %% 2) Verificar se os catálogos têm as colunas obrigatórias
                if ~all(ismember(["Modelo","Capacidade_Frio_kW","Capacidade_Quente_kW","SEER","SCOP","Classe"], ...
                        app.catalogoInt.Properties.VariableNames))
                    uialert(app.UIFigura,"Erro no catálogo de unidades interiores.","Erro");
                    return;
                end
    
                if ~all(ismember(["Modelo_Exterior","Capacidade_Frio_kW","Capacidade_Quente_kW","Max_Unidades","Classe","Ruido_dB_A_"], ...
                        app.catalogoExt.Properties.VariableNames))
                    uialert(app.UIFigura,"Erro no catálogo de unidades exteriores.","Erro");
                    return;
                end                 
                %% 3) Converter campos numéricos (evitar strings)
                app.catalogoInt.Capacidade_Frio_kW   = str2double(string(app.catalogoInt.Capacidade_Frio_kW));
                app.catalogoInt.Capacidade_Quente_kW = str2double(string(app.catalogoInt.Capacidade_Quente_kW));
                app.catalogoInt.SEER = str2double(string(app.catalogoInt.SEER));
                app.catalogoInt.SCOP = str2double(string(app.catalogoInt.SCOP));
    
                app.catalogoExt.Capacidade_Frio_kW   = str2double(string(app.catalogoExt.Capacidade_Frio_kW));
                app.catalogoExt.Capacidade_Quente_kW = str2double(string(app.catalogoExt.Capacidade_Quente_kW));
                app.catalogoExt.Max_Unidades        = str2double(string(app.catalogoExt.Max_Unidades));
                app.catalogoExt.Ruido_dB_A_         = str2double(string(app.catalogoExt.Ruido_dB_A_));
    
                %% 4) Verificar se existem divisões introduzidas
                T = app.TabelaDivisoes.Data;
                if isempty(T)
                    uialert(app.UIFigura,"Sem divisões definidas.","Erro");
                    return;
                end    
                % Garantir que tabelas de isolamentos estão carregadas
                if isempty(app.tabelaIsolamentos) || ~istable(app.tabelaIsolamentos) || height(app.tabelaIsolamentos)==0
                    app.tabelaIsolamentos = readtable('isolamentos.xlsx','Sheet','Parede','TextType','string');
                end
                if isempty(app.tabelaJanelas) || ~istable(app.tabelaJanelas) || height(app.tabelaJanelas)==0
                    app.tabelaJanelas = readtable('isolamentos.xlsx','Sheet','Janela','TextType','string');
                end

                %% 5) Definir parâmetros de cálculo
                T_int_ver = 24; % Temperatura interior verão (°C)
                T_int_inv = 20; % Temperatura interior inverno (°C)
                T_ext_ver = app.theta_ver_REF; % Temp. projeto exterior verão
                T_ext_inv = app.theta_inv_REF; % Temp. projeto exterior inverno
                margem = 1.10; % Margem segurança (10%)
                rho_ar = 1.2;  % Densidade do ar (kg/m³)
                cp_ar  = 1005; % Calor específico (J/kg·K)    

                %% 6) Preparar estrutura para resultados               
                n = size(T,1); % Número de divisões
                resultados_cell = cell(n,10);   % pré-alocação máxima
                idxRes = 0;                   
                
                carga_total_arrefecimento = 0;
                carga_total_aquecimento = 0;

                %% 7) CÁLCULO divisão a divisão                
               
                for i = 1:n                   

                    % 1) LER DADOS DA DIVISÃO

                    nomeDivisao = string(T{i,1});

                    % LER SEMPRE COMO NÚMERO (mesmo que venha como texto)
                    area     = str2double(string(T{i,2}));
                    altura   = str2double(string(T{i,3}));
                    pessoas  = str2double(string(T{i,4}));
                    area_jan = str2double(string(T{i,5}));
                    orient = upper(strtrim(string(T{i,6})));
                    tipoParede = strtrim(string(T{i,7}));
                    tipoJanela = strtrim(string(T{i,8}));

                    divisaoIgnorada = false;

                    % Se algo vier vazio/NaN, trava já (não deixes virar 0 silenciosamente)
                    if any(isnan([area, altura, pessoas, area_jan])) || area <= 0 || altura <= 0
                        uialert(app.UIFigura, ...
                            "Existem valores inválidos na tabela para a divisão: " + nomeDivisao + ...
                            newline + "Confirma que carregaste ENTER após editar as células.", ...
                            "Dados inválidos");
                        return;
                    end

                    
                    % AJUSTE AUTOMÁTICO PARA 2 UNIDADES
                  
                    % 2) BUSCAR ISOLAMENTOS
                    idxP = find(strcmp(app.tabelaIsolamentos.Tipo_Parede, tipoParede), 1);

                    if isempty(idxP)
                        uialert(app.UIFigura, ...
                            'Tipo de parede não encontrado na tabela de isolamentos.', ...
                            'Erro');
                        return;
                    end

                    U_parede = str2double(app.tabelaIsolamentos.U_W_m2K(idxP));

                    idxJ = find(strcmp(app.tabelaJanelas.Tipo_Janela, tipoJanela), 1);

                    if isempty(idxJ)
                        uialert(app.UIFigura, ...
                            'Tipo de janela não encontrado na tabela de isolamentos.', ...
                            'Erro');
                        return;
                    end

                    U_jan = str2double(app.tabelaJanelas.U_W_m2K(idxJ));
                    SHGC = str2double(app.tabelaJanelas.SHGC(idxJ));

                    % Guardar valores originais (para o split)
                    area0     = area;
                    area_jan0 = area_jan;

                    % Inicial
                    num_unidades_int = 1;
         
                    % Vamos tentar 2 vezes: 1- normal e 2 divido em caso de confirmação
                    for tentativa = 1:2

                        % Se for segunda tentativa, já estamos em modo split(2 unidades)
                        % (a alteração do area/area_jan é feita quando o utilizador carrega "Sim")
                       
                        % 3) CÁLCULO DAS CARGAS
                   
                        perimetro = 4 * sqrt(max(area,1));
                        area_parede_ext = perimetro * altura * 0.30;

                        dT_ver = max(0, T_ext_ver - T_int_ver);
                        dT_inv = max(0, T_int_inv - T_ext_inv);

                        Q_parede_frio   = U_parede * area_parede_ext * dT_ver;
                        Q_parede_quente = U_parede * area_parede_ext * dT_inv;
                        Q_jan_frio      = U_jan * area_jan * dT_ver;
                        Q_jan_quente    = U_jan * area_jan * dT_inv;

                        switch upper(orient)
                            case "N", I_kWh = app.G_verao_N;
                            case "E", I_kWh = app.G_verao_E;
                            case "S", I_kWh = app.G_verao_S;
                            case "O", I_kWh = app.G_verao_O;
                            otherwise, I_kWh = app.G_verao_S;
                        end

                        I_ver_Wm2 = I_kWh * 1000 / (30*24);
                        Q_solar_ver = area_jan * SHGC * I_ver_Wm2;

                        Q_sens_pess = pessoas * 90;
                        Q_lat_pess  = pessoas * 50 * (2.26e6/3.6e6);
                        Q_ilum      = area * 7.5;
                        Q_equip     = area * 10;

                        Vdot = (50.4 * pessoas) / 3600;
                        m_dot = rho_ar * Vdot;

                        Q_vent_frio   = m_dot * cp_ar * dT_ver;
                        Q_vent_quente = m_dot * cp_ar * dT_inv;

                        Q_frio_W = Q_parede_frio + Q_jan_frio + Q_vent_frio + ...
                            Q_solar_ver + Q_sens_pess + Q_lat_pess + Q_ilum + Q_equip;

                        carga_arref_kW = max(0, Q_frio_W * margem / 1000);

                        Q_aquec_W = Q_parede_quente + Q_jan_quente + Q_vent_quente - ...
                            (Q_sens_pess + Q_lat_pess + Q_ilum + Q_equip);

                        carga_aquec_kW = max(0, Q_aquec_W * margem / 1000);
                       
                        % 4) SELEÇÃO DA UNIDADE INTERIOR
                       
                        modelo_selecionado_int = "-";
                        margem_potencia = Inf;

                        pot_unid_arrefecimento = NaN;
                        pot_unid_aquecimento  = NaN;
                        SEER_unidade = NaN;
                        SCOP_unidade = NaN;
                        classe_energetica_unidade = "-";

                        for a = 1:height(app.catalogoInt)
                            potF = app.catalogoInt.Capacidade_Frio_kW(a);
                            potQ = app.catalogoInt.Capacidade_Quente_kW(a);

                            if potF >= carga_arref_kW && potQ >= carga_aquec_kW
                                dif = potF - carga_arref_kW;
                                if dif < margem_potencia
                                    margem_potencia = dif;
                                    modelo_selecionado_int = app.catalogoInt.Modelo(a);
                                    pot_unid_arrefecimento = potF;
                                    pot_unid_aquecimento  = potQ;
                                    SEER_unidade = app.catalogoInt.SEER(a);
                                    SCOP_unidade = app.catalogoInt.SCOP(a);
                                    classe_energetica_unidade = app.catalogoInt.Classe(a);
                                end
                            end
                        end

                        % Se conseguiu, termina aqui
                        if modelo_selecionado_int ~= "-"
                            break;
                        end

                        % Se não conseguiu na 1ª tentativa: perguntar ao utilizador
                        if tentativa == 1
                            msg = sprintf([ ...
                                'Nenhuma Unidade Interior satisfaz as características do espaço:\n\n' ...
                                'Divisão: %s\n\n' ...
                                'Deseja adicionar mais uma unidade interior?\n' ...
                                '(O programa irá recalcular assumindo metade da área e metade da área de janela.)' ], nomeDivisao);

                            resposta = uiconfirm(app.UIFigura, msg, ...
                                'Divisão incompatível', ...
                                'Options', {'Sim','Não'}, ...
                                'DefaultOption','Sim', ...
                                'Icon','warning');

                            if strcmp(resposta,'Sim')
                                % Divisão de área e janela 
                                area     = area0 / 2;
                                area_jan = area_jan0 / 2;
                                num_unidades_int = 2;

                                % continua para a 2ª tentativa automaticamente
                            else
                                uialert(app.UIFigura, ...
                                    "Não foi possível dimensionar a divisão: " + nomeDivisao, ...
                                    "Dimensionamento impossível");

                                divisaoIgnorada = true;   % <<< MARCA PARA REMOVER
                                break;
                            end

                        end                      
                    end
                    if divisaoIgnorada
                        continue;  
                    end

                    if modelo_selecionado_int == "-"
                        if num_unidades_int == 2
                            uialert(app.UIFigura, ...
                                "Mesmo com duas unidades interiores não foi possível dimensionar a divisão: " + nomeDivisao, ...
                                "Erro");
                        end
                        continue;
                    end

                    carga_total_arrefecimento = carga_total_arrefecimento + carga_arref_kW;
                    carga_total_aquecimento   = carga_total_aquecimento   + carga_aquec_kW;
                              
                    
                    % 6) GUARDAR RESULTADOS

                    idxRes = idxRes + 1;

                    resultados_cell{idxRes,1}  = nomeDivisao;
                    resultados_cell{idxRes,2}  = round(carga_arref_kW,2);
                    resultados_cell{idxRes,3}  = round(carga_aquec_kW,2);
                    resultados_cell{idxRes,4}  = modelo_selecionado_int;
                    resultados_cell{idxRes,5}  = pot_unid_arrefecimento;
                    resultados_cell{idxRes,6}  = pot_unid_aquecimento;
                    resultados_cell{idxRes,7}  = SEER_unidade;
                    resultados_cell{idxRes,8}  = SCOP_unidade;
                    resultados_cell{idxRes,9}  = classe_energetica_unidade;
                    resultados_cell{idxRes,10} = num_unidades_int;
                                      
                end

                % === nenhuma divisão foi dimensionada ===
                if idxRes == 0
                    % Garantir que não ficam resultados antigos visíveis
                    app.TabelaResultados.Data = {};
                    app.Resumo.Text = "";

                    % Ir diretamente para o separador Fim
                    app.GrupoAbas.SelectedTab = app.Abafim;
                    return;
                end

                
                %% 8) Seleção de unidades exteriores (1, 2 ou 3 unidades exteriores)
                resultados_cell = resultados_cell(1:idxRes, :);
                validRows = ~cellfun(@isempty, resultados_cell(:,4));  % modelo interior não vazio
                totalUnid = sum(cell2mat(resultados_cell(validRows,10)));

                maxUnidadesExt = 7;
                margem_sobredimensionamento = Inf;
                solucao_valida = false;

                modelos_exteriores_selecionados = "";
                pot_total_arrefecimento = NaN;
                pot_total_aquecimento   = NaN;
                classe_energetica_exterior = "";
                ruido_exterior_dB  = NaN;

                nExt = height(app.catalogoExt);

                for k = 1:maxUnidadesExt

                    if solucao_valida
                        break;
                    end

                    combos = nchoosek(1:nExt, k);

                    for c = 1:size(combos,1)

                        idx = combos(c,:);

                        potF = sum(app.catalogoExt.Capacidade_Frio_kW(idx));
                        potQ = sum(app.catalogoExt.Capacidade_Quente_kW(idx));
                        maxU = sum(app.catalogoExt.Max_Unidades(idx));

                        if maxU >= totalUnid && ...
                                potF >= carga_total_arrefecimento && ...
                                potQ >= carga_total_aquecimento

                            excesso = potF - carga_total_arrefecimento;

                            if excesso < margem_sobredimensionamento
                                margem_sobredimensionamento = excesso;
                                solucao_valida = true;

                                modelos_exteriores_selecionados = ...
                                    strjoin(app.catalogoExt.Modelo_Exterior(idx), " + ");

                                pot_total_arrefecimento = potF;
                                pot_total_aquecimento   = potQ;

                                if k == 1
                                    classe_energetica_exterior = app.catalogoExt.Classe(idx(1));
                                else
                                    classe_energetica_exterior = "MISTO";
                                end

                                ruido_exterior_dB = max(app.catalogoExt.Ruido_dB_A_(idx));
                            end
                        end
                    end
                end

              % --- Nenhuma solução encontrada ---
              if ~solucao_valida

                  escolha = uiconfirm(app.UIFigura, ...
                      ['Lamentamos, mas nenhuma combinação de unidades exteriores ' ...
                      'satisfaz a carga total da habitação.'], ...
                      'Sem solução encontrada', ...
                      'Options', {'Recomeçar','Sair'}, ...
                      'DefaultOption','Recomeçar', ...
                      'CancelOption','Sair', ...
                      'Icon','error');

                  switch escolha
                      case 'Recomeçar'              
                          app.GrupoAbas.SelectedTab = app.AbaInicio;
                          return;

                      case 'Sair'
                          delete(app.UIFigura);
                          return;
                  end
                  modelos_exteriores_selecionados = "N/A";
                  pot_total_arrefecimento = NaN;
                  pot_total_aquecimento   = NaN;
                  classe_energetica_exterior = "N/D";
                  ruido_exterior_dB  = NaN;
              end    
                %% 9) Cálculo do consumo real (kWh e €)
               
                % HORAS DE USO CONFORME SCE/REH
                horas_inverno = app.Mref * 30 * 8;   % Inverno depende da região
                horas_verao   = 4 * 30 * 6;          % 4 meses × 6 h/dia = 720 h/ano           
                preco_kWh     = 0.1424;              % €/kWh (Portugal 2025)
    
                consumo_total_frio_kWh   = 0;
                consumo_total_quente_kWh = 0;
    
               for i = 1:idxRes
                    carga_f = resultados_cell{i,2};
                    carga_q = resultados_cell{i,3};
                    SEER_i  = resultados_cell{i,7};
                    SCOP_i  = resultados_cell{i,8};
    
                    if ~isscalar(SEER_i) || isnan(SEER_i) || SEER_i <= 0
                        SEER_i = 4;
                    end
    
                    if ~isscalar(SCOP_i) || isnan(SCOP_i) || SCOP_i <= 0
                        SCOP_i = 3;
                    end
       
                    nU = resultados_cell{i,10};
    
                    consumo_frio_kWh   = nU * carga_f * horas_verao   / SEER_i;
                    consumo_quente_kWh = nU * carga_q * horas_inverno / SCOP_i;
        
                    consumo_total_frio_kWh   = consumo_total_frio_kWh   + consumo_frio_kWh;
                    consumo_total_quente_kWh = consumo_total_quente_kWh + consumo_quente_kWh;
                end
    
                consumo_total_kWh   = consumo_total_frio_kWh + consumo_total_quente_kWh;
                custo_total_euros   = consumo_total_kWh * preco_kWh;
               
                %%  10) Preencher tabela de resultados
                 
    app.resultados = cell2table(resultados_cell, ...
        'VariableNames',{'Divisao','Carga_Arref_kW','Carga_Aquec_kW', ...
        'Modelo','Pot_Arref_kW','Pot_Aquec_kW', ...
        'SEER','SCOP','Classe','Unidades'});
    
    app.TabelaResultados.Data = app.resultados;
   app.TabelaResultados.ColumnName = cellstr(app.resultados.Properties.VariableNames);   
                %% 11) Resumo final
               
                app.Resumo.Text = sprintf([ ...
                    'Divisões: %d | Carga Total Arref.: %.2f kW | Carga Total Aquec.: %.2f kW\n\n' ...
                    'Unidades Exteriores Selecionadas: \n%s\n\n' ...
                    'Potência Total Arref. Disponível: %.1f kW | Potência Total Aquec.: %.1f kW\n' ...
                    'Classe: %s | Ruído Máximo: %.0f dB(A)\n\n' ...
                    'Consumo Estimado:\n' ...
                    '   Arrefecimento: %.0f kWh/ano | Aquecimento: %.0f kWh/ano\n' ...
                    '   Consumo Total: %.0f kWh/ano | Custo Total: %.2f €/ano\n\n' ...                
                    'Para mais informações sobre custos de equipamentos e serviços, \n' ...
                    'deverá contactar os serviços oficiais da Daikin Portugal através \n' ...
                    ' do contacto telefónico +351 214 268 700.'], ...
                     idxRes, carga_total_arrefecimento, carga_total_aquecimento, ...
                    modelos_exteriores_selecionados, pot_total_arrefecimento, pot_total_aquecimento, ...
                    classe_energetica_exterior, ruido_exterior_dB, ...
                    consumo_total_frio_kWh, consumo_total_quente_kWh, ...
                    consumo_total_kWh, custo_total_euros);
                    
                app.GrupoAbas.SelectedTab = app.AbaResultados;
        end

        % Button pushed function: BotaoExportar
        function BotaoExportarButtonPushed(app, event)
                 
            % AVISO ANTES DA EXPORTAÇÃO
            
            resposta = uiconfirm(app.UIFigura, ...
                ['Ao exportar os dados, verifique que o ficheiro Resultados_Climarve.xlsx ' ...
                'é guardado na pasta "Projeto".' newline newline ...
                'Certifique-se igualmente que o template utilizado corresponde a ' ...
                'Template_Climarve.xlsx. que se encontra também na pasta "Projeto"' newline newline ...
                'Caso contrário, não será possível realizar a exportação correta dos dados.'], ...
                'Informação', ...
                'Options', {'OK'}, ...
                'Icon', 'info', ...
                'DefaultOption', 1);

            % Se não clicar em OK, parar
            if ~strcmp(resposta, 'OK')
                return;
            end

    % 1) Verificar se existem resultados na app
    
    if isempty(app.resultados)
        uialert(app.UIFigura,'Sem resultados para exportar.','Aviso');
        return;
    end

    % 2) Perguntar ao utilizador onde guardar o Excel
    
    [file, path] = uiputfile({'*.xlsx','Excel (*.xlsx)'}, ...
                             'Guardar relatório', 'Resultados_Climarve.xlsx');

    % Se o utilizador cancelar, parar
    if isequal(file,0)
        return;
    end

    % Caminho do ficheiro final
    excelPath = fullfile(path, file);
   
    % 3) Localização do template do relatório
    
    [templFile, templPath] = uigetfile('*.xlsx', ...
        'Selecionar template de resultados');

    if isequal(templFile,0)
        return;
    end

    templatePath = fullfile(templPath, templFile);

    % Confirmar se o template existe
    if ~isfile(templatePath)
        uialert(app.UIFigura, "Template não encontrado.", "Erro");
        return;
    end

    % 4) Criar o novo Excel a partir do template
   
    copyfile(templatePath, excelPath, 'f');   % 'f' = força a substituir

    % 5) Abrir o Excel através do Windows
    
    excel = actxserver('Excel.Application');
    excel.Visible       = false;     % não mostrar a janela
    excel.DisplayAlerts = false;     % impedir pop-ups

    wb = excel.Workbooks.Open(excelPath);
    ws = wb.Sheets.Item(1);          % primeira folha do Excel

    % 7) ESCREVER os dados da app no Excel
    
    dados = table2cell(app.resultados);  
    n = size(dados,1);        % número real de divisões calculadas
    maxLinhas = 9;            % limite permitido no relatório

    linhasUsadas = min(n, maxLinhas);

    primeiraLinha = 5;                       % os dados começam na linha 5
    ultimaLinha   = primeiraLinha + linhasUsadas - 1;

    destino = sprintf("D%d:M%d", primeiraLinha, ultimaLinha);

    % Escrever apenas até 9 linhas
    ws.Range(destino).Value = dados(1:linhasUsadas, :);

    % Se tiver menos de 9 linhas, limpar o resto
    if linhasUsadas < maxLinhas
        ws.Range(sprintf("D%d:L13", ultimaLinha+1)).ClearContents;
    end
    
    % 8) EXPORTAR O TEXTO DO RESUMO COMO IMAGEM
    
    textoResumo = app.Resumo.Text;

    % Criar figura temporária escondida
    fig = figure('Visible','off');
    annotation(fig,'textbox',[0 0 1 1], 'String', textoResumo, ...
        'FontSize', 12, 'Interpreter','none');  

    % Tirar "foto" da figura
    frame = getframe(fig);
    resumoImg = fullfile(tempdir, 'resumo_climarve.png');
    imwrite(frame.cdata, resumoImg);   % guardar como PNG
    close(fig);

    % Inserir imagem no Excel (por baixo da tabela)
    ws.Shapes.AddPicture(resumoImg, 0, 1, ...
                         80, 350, ...   % posição X,Y
                         600, 300);     % largura, altura

    
    % 9) Guardar e fechar Excel
    
    wb.Save();
    wb.Close(false);
    excel.Quit();
    delete(excel);
    
    % 10) Mensagem final para o utilizador
 
    uialert(app.UIFigura, ...
        "Excel gerado com sucesso em:" + newline + excelPath, ...
        "Sucesso", "Icon","success");   
    app.GrupoAbas.SelectedTab = app.Abafim;
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            selection = uiconfirm(app.UIFigura, ...
                'Deseja sair da aplicação? Todos os seus dados serão eliminados.', ...
                'Confirmar saída', ...
                'Options', {'Sim', 'Não'}, ...
                'DefaultOption', 'Não', ...
                'Icon', 'warning');

            if strcmp(selection, 'Sim')
                delete(app);   
             end
        end

        % Button pushed function: SairButton_2
        function SairButton_2Pushed(app, event)
            SairButtonPushed(app, event);
        end

        % Button pushed function: SairButton_3
        function SairButton_3Pushed(app, event)
            SairButtonPushed(app, event);
        end

        % Button pushed function: SairButton_4
        function SairButton_4Pushed(app, event)
            SairButtonPushed(app, event);
        end

        % Button pushed function: SairButton_5
        function SairButton_5Pushed(app, event)
            SairButtonPushed(app, event);
        end

        % Button pushed function: RecomecarButton
        function RecomecarButtonPushed(app, event)
            % - Volta para a aba inicial
            % - Limpa os campos principais (Região, Tipologia, Divisões e Resultados)
            % - Remove tabelas e painéis gerados automaticamente
           
            % 1. Confirmação antes de apagar tudo
            selection = uiconfirm(app.UIFigura, ...
                'Deseja recomeçar? Todos os dados serão apagados.', ...
                'Confirmar recomeço', ...
                'Options', {'Sim','Não'}, ...
                'Icon', 'warning');

            if strcmp(selection, 'Não')
                return;  
            end

            % 2. Voltar para a aba inicial
            app.GrupoAbas.SelectedTab = app.AbaInicio;

            % 3. Limpar campos da seção Região
            app.DistritoDropdown.Value = app.DistritoDropdown.Items{1};  % volta ao primeiro item válido
            app.ConcelhoDropDown.Items = {};        % esvazia a lista
            app.ConcelhoDropDown.Value = {};        % limpa o Value
            app.ConcelhoDropDown.Enable = 'off';    % desativa o dropdown
            app.ConcelhoLabel.Enable = 'off';

            app.mensagemP.Text = '';
            
            % 4. Limpar campos de Tipologia
            app.TipologiaDropDown.Value = app.TipologiaDropDown.Items{1};
            app.NovaDivisoEditField.Value = '';

            % 5. Limpar Tabela de Divisões
            app.TabelaDivisoes.Data = {};
            app.gerouTipologia = false; % Reset da flag de tipologia
            app.envolventeEditada = false;

            % 6. Apagar painéis gerados automaticamente (Parede e Janela)
            delete(findall(app.IsolamentoParedePanel,'Tag','DivisaoItem'));
            delete(findall(app.IsolamentoJanelasPanel,'Tag','DivisaoItem'));

            % 7. Limpar Tabela de Resultados
            app.TabelaResultados.Data = {};
            app.Resumo.Text = '';

            % 8. Mensagem final ao utilizador
            uialert(app.UIFigura, ...
                'Aplicação reiniciada com sucesso.', ...
                'Recomeço concluído', ...
                'Icon','success');
        end

        % Button pushed function: RecomecarButton_2
        function RecomecarButton_2Pushed(app, event)
            RecomecarButtonPushed(app, event);
        end

        % Button pushed function: RecomecarButton_3
        function RecomecarButton_3Pushed(app, event)
            RecomecarButtonPushed(app, event);
        end

        % Button pushed function: RecomecarButton_4
        function RecomecarButton_4Pushed(app, event)
            RecomecarButtonPushed(app, event);
        end

        % Button pushed function: LimparButton
        function LimparButtonPushed(app, event)
            selection = uiconfirm(app.UIFigura, ...
                'Deseja limpar os dados? Todos os dados serão apagados.', ...
                'Confirmar recomeço', ...
                'Options', ["Sim","Não"], ...   % - usa string array
                'Icon', 'warning');

            if selection == "Não"              % - comparando string corretamente
                return;
            end
            % Limpar apenas elementos da ABA REGIÃO
            app.DistritoDropdown.Items = [{''} app.DistritoDropdown.Items]; % Garante opção vazia
            app.DistritoDropdown.Value = '';
            app.ConcelhoDropDown.Items = {''};
            app.ConcelhoDropDown.Value = '';
            app.ConcelhoDropDown.Enable = 'off';
            app.ConcelhoLabel.Enable = 'off';
            app.mensagemP.Text = '';
            uialert(app.UIFigura,'Os dados da secção Região foram limpos.','Limpeza concluída','Icon','success');
        end

        % Button pushed function: LimparButton_2
        function LimparButton_2Pushed(app, event)
            selection = uiconfirm(app.UIFigura, ...
                'Deseja limpar os dados? Todos os dados serão apagados.', ...
                'Confirmar recomeço', ...
                'Options', ["Sim","Não"], ...   % - usa string array
                'Icon', 'warning');

            if selection == "Não"              % - comparando string corretamente
                return;
            end
            % Garantir que existe opção vazia antes de limpar
            if ~ismember("", app.TipologiaDropDown.Items)
                app.TipologiaDropDown.Items = [{''} app.TipologiaDropDown.Items];
            end
            app.TipologiaDropDown.Value = '';
            app.NovaDivisoEditField.Value = '';
            app.TabelaDivisoes.Data = {};
            delete(app.IsolamentoParedePanel.Children);
            delete(app.IsolamentoJanelasPanel.Children);
            app.gerouTipologia = false;% Reset da flag
            app.envolventeEditada = false;

            uialert(app.UIFigura,'Dados apagados da secção Tipologia/Envolvente.','Limpeza concluída','Icon','success');
        end

        % Button pushed function: LimparButton_3
        function LimparButton_3Pushed(app, event)
            app.TabelaDivisoes.Data = {}; uialert(app.UIFigura,'Tabela de divisões limpa.','Limpeza concluída','Icon','success');
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            app.GrupoAbas.SelectedTab = app.AbaInicio;
        end

        % Button pushed function: AnteriorButton_2
        function AnteriorButton_2Pushed(app, event)
            app.GrupoAbas.SelectedTab = app.AbaRegiao;
        end

        % Button pushed function: AnteriorButton_3
        function AnteriorButton_3Pushed(app, event)
            app.GrupoAbas.SelectedTab = app.AbaEnvolvente;
        end

        % Button pushed function: AnteriorButton_4
        function AnteriorButton_4Pushed(app, event)
            app.GrupoAbas.SelectedTab = app.AbaDivisoes;
        end

        % Button pushed function: RecomearButton
        function RecomearButtonPushed(app, event)
            RecomecarButtonPushed(app, event);
        end

        % Button pushed function: SairButton_6
        function SairButton_6Pushed(app, event)
            SairButtonPushed(app, event); 
        end

        % Button pushed function: TabeladasRegiesButton
        function TabeladasRegiesButtonPushed(app, event)
            appPath = fileparts(mfilename('fullpath'));
            excelFile = fullfile(appPath, 'SCE_Zonas_por_Distrito_COMPLETO.xlsx');

            if isfile(excelFile)
                open(excelFile);
            else
                uialert(app.UIFigure, 'Excel SCE não encontrado.', 'Erro');
            end
        end

        % Button pushed function: TabeladeIsolamentosButton
        function TabeladeIsolamentosButtonPushed(app, event)
            appPath = fileparts(mfilename('fullpath'));
    excelFile = fullfile(appPath, 'isolamentos.xlsx');

    if isfile(excelFile)
        open(excelFile);
    else
        uialert(app.UIFigure, 'Excel Isolamentos não encontrado.', 'Erro');
    end
        end

        % Button pushed function: TabelaDasUnidadesDAIKINButton
        function TabelaDasUnidadesDAIKINButtonPushed(app, event)
            appPath = fileparts(mfilename('fullpath'));
    excelFile = fullfile(appPath, 'daikin_unidades.xlsx');

    if isfile(excelFile)
        open(excelFile);
    else
        uialert(app.UIFigure, 'Excel Daikin não encontrado.', 'Erro');
    end
        end

        % Button pushed function: AnexosButton
        function AnexosButtonPushed(app, event)
            appPath = fileparts(mfilename('fullpath'));
            pastaAnexos = fullfile(appPath, 'Anexos');

            if ~isfolder(pastaAnexos)
                uialert(app.UIFigura, ...
                    'A pasta Anexos não foi encontrada', ...
                    'Erro');
                return;
            end

            winopen(pastaAnexos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigura = uifigure('Visible', 'off');
            app.UIFigura.Name = 'MATLAB App';
            app.GrupoAbas = uitabgroup(app.UIFigura);
            app.AbaInicio = uitab(app.GrupoAbas);
            app.AbaInicio.Title = 'Inicio';
            app.ImagemLogo = uiimage(app.AbaInicio);
            app.ImagemLogo.ImageSource = fullfile(pathToMLAPP, 'Imagem WhatsApp 2025-10-28 às 23.26.04_3f416c00.jpg');
            app.BotaoIniciar = uibutton(app.AbaInicio, 'push');
            app.BotaoIniciar.ButtonPushedFcn = createCallbackFcn(app, @BotaoIniciarButtonPushed, true);
            app.BotaoIniciar.Text = 'Iniciar';
            app.ClimarveDimensionamentodearcondicionadoLabel = uilabel(app.AbaInicio);
            app.ClimarveDimensionamentodearcondicionadoLabel.Text = 'Climarve - Dimensionamento de ar-condicionado ';
            app.Image2 = uiimage(app.AbaInicio);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Logo MCE.png');
            app.Image3 = uiimage(app.AbaInicio);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Instituto_Superior_de_Engenharia_de_Coimbra_logo.png');
            app.SobreogrupoButton = uibutton(app.AbaInicio, 'push');
            app.SobreogrupoButton.ButtonPushedFcn = createCallbackFcn(app, @SobreogrupoButtonPushed, true);
            app.SobreogrupoButton.Text = 'Sobre o grupo';
            app.SairButton = uibutton(app.AbaInicio, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.TabeladasRegiesButton = uibutton(app.AbaInicio, 'push');
            app.TabeladasRegiesButton.ButtonPushedFcn = createCallbackFcn(app, @TabeladasRegiesButtonPushed, true);
            app.TabeladasRegiesButton.Text = 'Tabela das Regiões';
            app.TabeladeIsolamentosButton = uibutton(app.AbaInicio, 'push');
            app.TabeladeIsolamentosButton.ButtonPushedFcn = createCallbackFcn(app, @TabeladeIsolamentosButtonPushed, true);
            app.TabeladeIsolamentosButton.Text = 'Tabela de Isolamentos';
            app.TabelaDasUnidadesDAIKINButton = uibutton(app.AbaInicio, 'push');
            app.TabelaDasUnidadesDAIKINButton.ButtonPushedFcn = createCallbackFcn(app, @TabelaDasUnidadesDAIKINButtonPushed, true);
            app.TabelaDasUnidadesDAIKINButton.Text = 'Tabela Das Unidades DAIKIN';
            app.AnexosButton = uibutton(app.AbaInicio, 'push');
            app.AnexosButton.ButtonPushedFcn = createCallbackFcn(app, @AnexosButtonPushed, true);
            app.AnexosButton.Text = 'Anexos';
            app.AbaRegiao = uitab(app.GrupoAbas);
            app.AbaRegiao.Title = 'Região';
            app.DistritoLabel = uilabel(app.AbaRegiao);
            app.DistritoLabel.Text = 'Distrito ';
            app.DistritoDropdown = uidropdown(app.AbaRegiao);
            app.DistritoDropdown.Items = {'', 'Aveiro', 'Beja', 'Braga', 'Braganca', 'Castelo Branco', 'Coimbra', 'Evora', 'Faro', 'Guarda', 'Leiria', 'Lisboa', 'Portalegre', 'Porto', 'Santarem', 'Setubal', 'Viana do Castelo', 'Vila Real', 'Viseu'};
            app.DistritoDropdown.ValueChangedFcn = createCallbackFcn(app, @DistritoDropdownValueChanged, true);
            app.DistritoDropdown.Value = '';
            app.ConcelhoLabel = uilabel(app.AbaRegiao);
            app.ConcelhoLabel.Enable = 'off';
            app.ConcelhoLabel.Text = 'Concelho';
            app.ConcelhoDropDown = uidropdown(app.AbaRegiao);
            app.ConcelhoDropDown.Items = {};
            app.ConcelhoDropDown.ValueChangedFcn = createCallbackFcn(app, @ConcelhoDropDownValueChanged, true);
            app.ConcelhoDropDown.Enable = 'off';
            app.ConcelhoDropDown.Value = {};
            app.mensagemP = uilabel(app.AbaRegiao);
            app.mensagemP.Text = '';
            app.BotaoIrTipologiaEnvolvente = uibutton(app.AbaRegiao, 'push');
            app.BotaoIrTipologiaEnvolvente.ButtonPushedFcn = createCallbackFcn(app, @BotaoIrTipologiaEnvolventePushed, true);
            app.BotaoIrTipologiaEnvolvente.Text = 'Continuar';
            app.LimparButton = uibutton(app.AbaRegiao, 'push');
            app.LimparButton.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.LimparButton.Text = 'Limpar';
            app.RecomecarButton = uibutton(app.AbaRegiao, 'push');
            app.RecomecarButton.ButtonPushedFcn = createCallbackFcn(app, @RecomecarButtonPushed, true);
            app.RecomecarButton.Text = 'Recomeçar';
            app.SairButton_2 = uibutton(app.AbaRegiao, 'push');
            app.SairButton_2.ButtonPushedFcn = createCallbackFcn(app, @SairButton_2Pushed, true);
            app.SairButton_2.Text = 'Sair';
            app.AnteriorButton = uibutton(app.AbaRegiao, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.RegiodahabitaoLabel = uilabel(app.AbaRegiao);
            app.RegiodahabitaoLabel.Text = 'Região da habitação';
            app.Image4 = uiimage(app.AbaRegiao);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Captura de ecrã 2025-11-30 165301.png');
            app.AbaEnvolvente = uitab(app.GrupoAbas);
            app.AbaEnvolvente.Title = 'Tipologia/Envolvente';
            app.Image7 = uiimage(app.AbaEnvolvente);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'ChatGPT Image 10_12_2025, 19_25_25.png');
            app.Panel_2 = uipanel(app.AbaEnvolvente);
            app.TipologiaDropDownLabel_2 = uilabel(app.Panel_2);
            app.TipologiaDropDownLabel_2.Text = 'Tipologia';
            app.TipologiaDropDown = uidropdown(app.Panel_2);
            app.TipologiaDropDown.Items = {'T0', 'T1', 'T2', 'T3', 'T4', 'T5'};
            app.TipologiaDropDown.Value = 'T0';
            app.GerarDivisoesButton = uibutton(app.Panel_2, 'push');
            app.GerarDivisoesButton.ButtonPushedFcn = createCallbackFcn(app, @GerarDivisoesButtonPushed, true);
            app.GerarDivisoesButton.Text = 'Gerar Divisões';
            app.Panel = uipanel(app.AbaEnvolvente);
            app.Panel.Visible = 'off';
            app.GerarNovaDivisoButton = uibutton(app.Panel, 'push');
            app.GerarNovaDivisoButton.ButtonPushedFcn = createCallbackFcn(app, @GerarNovaDivisoButtonPushed, true);
            app.GerarNovaDivisoButton.Text = 'Gerar Nova Divisão';
            app.NovaDivisoEditFieldLabel = uilabel(app.Panel);
            app.NovaDivisoEditFieldLabel.Text = 'Nova Divisão';
            app.NovaDivisoEditField = uieditfield(app.Panel, 'text');
            app.AdicionarDivisoCheckBox = uicheckbox(app.AbaEnvolvente);
            app.AdicionarDivisoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdicionarDivisoCheckBoxValueChanged, true);
            app.AdicionarDivisoCheckBox.Text = 'Adicionar Divisão';
            app.DivisoesConteiner = uipanel(app.AbaEnvolvente);
            app.DivisoesConteiner.Title = 'Divisões ';
            app.IsolamentoParedePanel = uipanel(app.DivisoesConteiner);
            app.IsolamentoParedePanel.Title = 'Isolamento Parede';
            app.IsolamentoJanelasPanel = uipanel(app.DivisoesConteiner);
            app.IsolamentoJanelasPanel.Title = 'Isolamento Janelas';
            app.BotaoAplicarEnvolvente = uibutton(app.DivisoesConteiner, 'push');
            app.BotaoAplicarEnvolvente.ButtonPushedFcn = createCallbackFcn(app, @BotaoAplicarEnvolventeButtonPushed, true);
            app.BotaoAplicarEnvolvente.Text = 'AplicarEnvolvente';
            app.AnteriorButton_2 = uibutton(app.DivisoesConteiner, 'push');
            app.AnteriorButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButton_2Pushed, true);
            app.AnteriorButton_2.Text = 'Anterior';
            app.SairButton_3 = uibutton(app.DivisoesConteiner, 'push');
            app.SairButton_3.ButtonPushedFcn = createCallbackFcn(app, @SairButton_3Pushed, true);
            app.SairButton_3.Text = 'Sair';
            app.LimparButton_2 = uibutton(app.DivisoesConteiner, 'push');
            app.LimparButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimparButton_2Pushed, true);
            app.LimparButton_2.Text = 'Limpar';
            app.RecomecarButton_2 = uibutton(app.DivisoesConteiner, 'push');
            app.RecomecarButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecomecarButton_2Pushed, true);
            app.RecomecarButton_2.Text = 'Recomeçar';
            app.AbaDivisoes = uitab(app.GrupoAbas);
            app.AbaDivisoes.Title = 'Divisões';
            app.Image8 = uiimage(app.AbaDivisoes);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'ChatGPT Image 10_12_2025, 19_25_25.png');
            app.TabelaDivisoes = uitable(app.AbaDivisoes);
            app.TabelaDivisoes.ColumnName = {'Divisão'; 'Área (m²)'; 'Altura (m)'; 'Pessoas'; 'Área Janelas (m²)'; 'Orientação'; 'Parede'; 'Janela'};
            app.TabelaDivisoes.RowName = {};
            app.BotaoCalcular = uibutton(app.AbaDivisoes, 'push');
            app.BotaoCalcular.ButtonPushedFcn = createCallbackFcn(app, @BotaoCalcularPushed, true);
            app.BotaoCalcular.Text = 'Calcular';
            app.LimparButton_3 = uibutton(app.AbaDivisoes, 'push');
            app.LimparButton_3.ButtonPushedFcn = createCallbackFcn(app, @LimparButton_3Pushed, true);
            app.LimparButton_3.Text = 'Limpar';
            app.AnteriorButton_3 = uibutton(app.AbaDivisoes, 'push');
            app.AnteriorButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButton_3Pushed, true);
            app.AnteriorButton_3.Text = 'Anterior';
            app.RecomecarButton_3 = uibutton(app.AbaDivisoes, 'push');
            app.RecomecarButton_3.ButtonPushedFcn = createCallbackFcn(app, @RecomecarButton_3Pushed, true);
            app.RecomecarButton_3.Text = 'Recomeçar';
            app.SairButton_4 = uibutton(app.AbaDivisoes, 'push');
            app.SairButton_4.ButtonPushedFcn = createCallbackFcn(app, @SairButton_4Pushed, true);
            app.SairButton_4.Text = 'Sair';
            app.AbaResultados = uitab(app.GrupoAbas);
            app.AbaResultados.Title = 'Resultados';
            app.Image9 = uiimage(app.AbaResultados);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'ChatGPT Image 10_12_2025, 19_25_25.png');
            app.TabelaResultados = uitable(app.AbaResultados);
            app.TabelaResultados.ColumnName = {'Divisão'; 'Carga_Frio_kW'; 'Carga_Aquec_kW'; 'Modelo'; 'Pot_Arref_kW'; 'Pot_Aquec_kW'; 'SEER'; 'SCOP'; 'Classe'};
            app.TabelaResultados.RowName = {};
            app.Panel_3 = uipanel(app.AbaResultados);
            app.Resumo = uilabel(app.Panel_3);
            app.Resumo.Text = '';
            app.BotaoExportar = uibutton(app.AbaResultados, 'push');
            app.BotaoExportar.ButtonPushedFcn = createCallbackFcn(app, @BotaoExportarButtonPushed, true);
            app.BotaoExportar.Text = 'Exportar';
            app.AnteriorButton_4 = uibutton(app.AbaResultados, 'push');
            app.AnteriorButton_4.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButton_4Pushed, true);
            app.AnteriorButton_4.Text = 'Anterior';
            app.RecomecarButton_4 = uibutton(app.AbaResultados, 'push');
            app.RecomecarButton_4.ButtonPushedFcn = createCallbackFcn(app, @RecomecarButton_4Pushed, true);
            app.RecomecarButton_4.Text = 'Recomeçar';
            app.SairButton_5 = uibutton(app.AbaResultados, 'push');
            app.SairButton_5.ButtonPushedFcn = createCallbackFcn(app, @SairButton_5Pushed, true);
            app.SairButton_5.Text = 'Sair';
            app.Abafim = uitab(app.GrupoAbas);
            app.Abafim.Title = 'Fim';
            app.Image5 = uiimage(app.Abafim);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Imagem WhatsApp 2025-10-28 às 23.26.04_3f416c00.jpg');
            app.Image6 = uiimage(app.Abafim);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Logo MCE.png');
            app.ObrigadoporescolherCLIMARVELabel = uilabel(app.Abafim);
            app.ObrigadoporescolherCLIMARVELabel.Text = 'Obrigado por escolher CLIMARVE';
            app.ClculoPrecisoConfortoGarantidoLabel = uilabel(app.Abafim);
            app.ClculoPrecisoConfortoGarantidoLabel.Text = 'Cálculo Preciso, Conforto Garantido';
            app.SairButton_6 = uibutton(app.Abafim, 'push');
            app.SairButton_6.ButtonPushedFcn = createCallbackFcn(app, @SairButton_6Pushed, true);
            app.SairButton_6.Text = 'Sair';
            app.RecomearButton = uibutton(app.Abafim, 'push');
            app.RecomearButton.ButtonPushedFcn = createCallbackFcn(app, @RecomearButtonPushed, true);
            app.RecomearButton.Text = 'Recomeçar';
            % Show the figure after all components are created
            app.UIFigura.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClimarveFullApp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigura)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigura)
        end
    end
end